classdef GUI_Kelompok_7_m < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        InputImageTab             matlab.ui.container.Tab
        Panel_8                   matlab.ui.container.Panel
        InputYourMRIImagebmpformatbypressingtheRunbuttonbelowLabel  matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        ImageFilteringforBrainTumorDetectionusingMRIImageLabel  matlab.ui.control.Label
        Panel_3                   matlab.ui.container.Panel
        ImportedMRIImageLabel     matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        Panel_2                   matlab.ui.container.Panel
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        TumorDetectionTab         matlab.ui.container.Tab
        TabGroup2                 matlab.ui.container.TabGroup
        MRlOriginalImageTab       matlab.ui.container.Tab
        Panel_4                   matlab.ui.container.Panel
        UIAxes2                   matlab.ui.control.UIAxes
        MRIOriginalImageLabel     matlab.ui.control.Label
        ImageFilteringTab         matlab.ui.container.Tab
        TabGroup3                 matlab.ui.container.TabGroup
        BWImageTab                matlab.ui.container.Tab
        UIAxes3                   matlab.ui.control.UIAxes
        MedianFilterTab           matlab.ui.container.Tab
        UIAxes4                   matlab.ui.control.UIAxes
        WienerFilterTab           matlab.ui.container.Tab
        UIAxes5                   matlab.ui.control.UIAxes
        BinarizeTab               matlab.ui.container.Tab
        UIAxes6                   matlab.ui.control.UIAxes
        PredefinedSobelFilterTab  matlab.ui.container.Tab
        UIAxes7                   matlab.ui.control.UIAxes
        FilteredMRIImageLabel     matlab.ui.control.Label
        TumorDetectionTab_2       matlab.ui.container.Tab
        Panel_6                   matlab.ui.container.Panel
        UIAxes8                   matlab.ui.control.UIAxes
        TumorClassificationLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            %% Get MRI image from local storage
            [path, nofile] = imgetfile();
            if nofile
                msgbox(sprintf('Image Not Found'), 'Error', 'Warning');
                return
            end
            
            MRI_image = imread(path);
            imshow(MRI_image, 'Parent', app.UIAxes);
            imshow(MRI_image, 'Parent', app.UIAxes2);
            
                
            %% Image Filtering 
            MRI_image       = imresize(MRI_image,[224,224]);
            image_BW        = im2gray(MRI_image); % Gray Scaling
            imshow(image_BW, 'Parent', app.UIAxes3);
            medfilt_img     = medfilt2(image_BW); % Median Filter
            imshow(medfilt_img, 'Parent', app.UIAxes4);
            wiener_img      = wiener2(medfilt_img,[5 5]); % Wiener Filter
            imshow(medfilt_img, 'Parent', app.UIAxes5);
            binarize_img    = im2bw(wiener_img,.6);
            imshow(binarize_img, 'Parent', app.UIAxes6);
            
                
            %% Predefined Sobel horizontal edge-emphasizing Filter
            hy = -fspecial('sobel');
            hx = hy';
            Iy = imfilter(double(binarize_img), hy, 'replicate');
            Ix = imfilter(double(binarize_img), hx, 'replicate');
            
            gradmag = sqrt(Ix.^2 + Iy.^2);
            L = watershed(gradmag);
            Lrgb = label2rgb(L);
            
            imshow(Lrgb, 'Parent', app.UIAxes7);
                
            %% Image morphological structuring 
            se = strel('disk', 20);  % se - Structuring Element
            Io = imopen(binarize_img, se);                 % opening (erosi+dilasi) objek
            Ie = imerode(binarize_img , se);                % erosi/pengecilan ukuran objek
            
            Iobr = imreconstruct(Ie, binarize_img );        % rekonstruksi morfologis gambar
            Iobrd = imdilate(Iobr, se);                 % dilasi/perbesaran gambar
            Iobrcbr = imreconstruct(imcomplement(Iobrd), imcomplement(Iobr));
            Iobrcbr = imcomplement(Iobrcbr);            % complement nilai pixel objek
            
            I2 = binarize_img ;
            fgm = imregionalmax(Iobrcbr);               % peroleh titik-titik maksimum lokal objek 
            I2(fgm) = 255;                              % set nilai maksimum pixel = 255
            
            se2 = strel(ones(5,5));
            fgm2 = imclose(fgm, se2);
            fgm3 = imerode(fgm2, se2);
            fgm4 = bwareaopen(fgm3, 20);
            
            I3 = imerode(binarize_img , se);
            bw = im2bw(Iobrcbr);
            imshow(bw, 'Parent', app.UIAxes8);
            %% -----------------------------------------------------------------
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputImageTab = uitab(app.TabGroup);
            app.InputImageTab.Title = 'Input Image';
            app.Label = uilabel(app.InputImageTab);
            app.Label.Text = '';
            app.Panel = uipanel(app.InputImageTab);
            app.Panel_2 = uipanel(app.Panel);
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = {'Final Project of '; '"Signal Processing and'; 'Multimedia Services"'; ''; 'Topic: Image Filtering'; ''; ''};
            app.Panel_3 = uipanel(app.InputImageTab);
            app.UIAxes = uiaxes(app.Panel_3);
            zlabel(app.UIAxes, 'Z')
            app.ImportedMRIImageLabel = uilabel(app.Panel_3);
            app.ImportedMRIImageLabel.Text = 'Imported MRI Image';
            app.ImageFilteringforBrainTumorDetectionusingMRIImageLabel = uilabel(app.InputImageTab);
            app.ImageFilteringforBrainTumorDetectionusingMRIImageLabel.Text = 'Image Filtering for Brain Tumor Detection using MRI Image ';
            app.Panel_8 = uipanel(app.InputImageTab);
            app.RunButton = uibutton(app.Panel_8, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label_3 = uilabel(app.Panel_8);
            app.Label_3.Text = {'Group 7:'; ''; 'Rendy Christian'; '(2006529695)'; 'Valerie Olive Suryono'; '(2006574364) '};
            app.InputYourMRIImagebmpformatbypressingtheRunbuttonbelowLabel = uilabel(app.Panel_8);
            app.InputYourMRIImagebmpformatbypressingtheRunbuttonbelowLabel.Text = {'Input Your MRI Image'; '(.bmp format)'; 'by pressing the "Run"'; ' button below!'};
            app.TumorDetectionTab = uitab(app.TabGroup);
            app.TumorDetectionTab.Title = 'Tumor Detection';
            app.TabGroup2 = uitabgroup(app.TumorDetectionTab);
            app.MRlOriginalImageTab = uitab(app.TabGroup2);
            app.MRlOriginalImageTab.Title = 'MRl Original Image';
            app.MRIOriginalImageLabel = uilabel(app.MRlOriginalImageTab);
            app.MRIOriginalImageLabel.Text = 'MRI Original Image';
            app.Panel_4 = uipanel(app.MRlOriginalImageTab);
            app.UIAxes2 = uiaxes(app.Panel_4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ImageFilteringTab = uitab(app.TabGroup2);
            app.ImageFilteringTab.Title = 'Image Filtering';
            app.FilteredMRIImageLabel = uilabel(app.ImageFilteringTab);
            app.FilteredMRIImageLabel.Text = 'Filtered MRI Image';
            app.TabGroup3 = uitabgroup(app.ImageFilteringTab);
            app.BWImageTab = uitab(app.TabGroup3);
            app.BWImageTab.Title = 'BW Image';
            app.UIAxes3 = uiaxes(app.BWImageTab);
            zlabel(app.UIAxes3, 'Z')
            app.MedianFilterTab = uitab(app.TabGroup3);
            app.MedianFilterTab.Title = 'Median Filter';
            app.UIAxes4 = uiaxes(app.MedianFilterTab);
            zlabel(app.UIAxes4, 'Z')
            app.WienerFilterTab = uitab(app.TabGroup3);
            app.WienerFilterTab.Title = 'Wiener Filter';
            app.UIAxes5 = uiaxes(app.WienerFilterTab);
            zlabel(app.UIAxes5, 'Z')
            app.BinarizeTab = uitab(app.TabGroup3);
            app.BinarizeTab.Title = 'Binarize';
            app.UIAxes6 = uiaxes(app.BinarizeTab);
            zlabel(app.UIAxes6, 'Z')
            app.PredefinedSobelFilterTab = uitab(app.TabGroup3);
            app.PredefinedSobelFilterTab.Title = 'Predefined Sobel Filter';
            app.UIAxes7 = uiaxes(app.PredefinedSobelFilterTab);
            zlabel(app.UIAxes7, 'Z')
            app.TumorDetectionTab_2 = uitab(app.TabGroup2);
            app.TumorDetectionTab_2.Title = 'Tumor Detection';
            app.TumorClassificationLabel = uilabel(app.TumorDetectionTab_2);
            app.TumorClassificationLabel.Text = 'Tumor Classification';
            app.Panel_6 = uipanel(app.TumorDetectionTab_2);
            app.UIAxes8 = uiaxes(app.Panel_6);
            zlabel(app.UIAxes8, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Kelompok_7_m

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end